classdef FCM_parameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        helponfastfuzzyCmeansPanel      matlab.ui.container.Panel
        TextAreaLabel                   matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        SingleimageprocessingTab        matlab.ui.container.Tab
        currentmodulefastfuzzyCmeansPanel  matlab.ui.container.Panel
        imageButtonGroup                matlab.ui.container.ButtonGroup
        imagetofuzzyCmeansButton        matlab.ui.control.ToggleButton
        image_to_fuzzy_C_means_EditField  matlab.ui.control.EditField
        pathofsaveimageButton           matlab.ui.control.ToggleButton
        path_of_save_image_EditField    matlab.ui.control.EditField
        startButton                     matlab.ui.control.Button
        saveoutputButton                matlab.ui.control.Button
        FCMresultPanel                  matlab.ui.container.Panel
        FCMsegmentationcentersEditFieldLabel  matlab.ui.control.Label
        FCMsegmentationcentersEditField  matlab.ui.control.EditField
        saveparametersPanel             matlab.ui.container.Panel
        FCMparammEditFieldLabel         matlab.ui.control.Label
        FCMparammEditField              matlab.ui.control.NumericEditField
        saveallButton                   matlab.ui.control.Button
        outputfilenameEditFieldLabel    matlab.ui.control.Label
        filename_prefix_EditField       matlab.ui.control.EditField
        BatchimageprocessingTab         matlab.ui.container.Tab
        currentmodulefastfuzzyCmeansPanel_2  matlab.ui.container.Panel
        imageButtonGroup_2              matlab.ui.container.ButtonGroup
        pathofinputimagesButton         matlab.ui.control.ToggleButton
        image_to_fuzzy_C_means_EditField_2  matlab.ui.control.EditField
        pathofsaveimagesButton          matlab.ui.control.ToggleButton
        path_of_save_image_EditField_2  matlab.ui.control.EditField
        startandsaveButton              matlab.ui.control.Button
        FCMresultPanel_2                matlab.ui.container.Panel
        FCMsegmentationcentersEditField_2Label  matlab.ui.control.Label
        FCMsegmentationcentersEditField_2  matlab.ui.control.EditField
        saveparametersPanel_2           matlab.ui.container.Panel
        FCMparammEditField_2Label       matlab.ui.control.Label
        FCMparammEditField_2            matlab.ui.control.NumericEditField
        saveallButton_2                 matlab.ui.control.Button
        outputprefixLabel               matlab.ui.control.Label
        filename_prefix_EditField_2     matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Calling_app % 回调主APP
        input_img_path
        output_img
        output_prefix
        output_path
        m_value
        k_value
        membership_mat
        W_mat
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.Calling_app=mainapp;
            app.filename_prefix_EditField.Value="Please enter the prefix of the saved file";       
        end

        % Selection changed function: imageButtonGroup
        function imageButtonGroupSelectionChanged(app, event)
            selectedButton = app.imageButtonGroup.SelectedObject;
%             app.k_means_param_EditField.Value="请输入保存文件的前缀,后点击'filename prefix'";
            switch selectedButton.Text
                case 'image to fuzzy C-means'
                    [filename,path]=uigetfile({'*.nii.gz;*.nii'},'选择图片文件');
                    app.image_to_fuzzy_C_means_EditField.Value=[path,filename];
                    app.input_img_path=app.image_to_fuzzy_C_means_EditField.Value;
                case 'path of save image'
                    app.output_path = uigetdir('C:\Users\86133\Documents\MATLAB');
                    app.path_of_save_image_EditField.Value=app.output_path;
               
            end
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            info=load_untouch_nii(app.input_img_path);
            IMG=info.img;
            hdr=info.hdr;
%             K-means定初始聚类中心
            app.k_value=4;
%             [C_init] = kmeans_init(IMG,app.k_value,[],[]);
            img=int32(IMG(IMG~=0));
%             FCM
            [C,U,LUT,H]=FastFCMeans(img(:),app.k_value,app.m_value,false) ;% perform FCM segmentation
            disp('FCM segmentation centers are:' );
            disp(C);
            app.membership_mat=U;
            center='';
%             在前端打印centers
            for ii=1:length(C)
                center=[center,num2str(C(ii))];
                if ii~=length(C)
                    center=[center,','];
                end
            end
            app.FCMsegmentationcentersEditField.Value=center;
            L=LUT2label(img,LUT);
            % Get FCM results获得FCM处理后的结果
            FCM_seg=zeros(size(IMG));
            OriginId=find(IMG~=0);
            FCM_seg(OriginId(L(:)==app.k_value))=1;
            if app.k_value>3
                FCM_seg(OriginId(L(:)==app.k_value-1))=1;
            end
            % W is ratio of each class
            W = zeros(1,K);
            for c = 1:4
                W(c) = length(find(L(:) == c)) / numel(IMG(IMG ~= 0));
            end
            app.W_mat=W;
            app.output_img=make_nii(FCM_seg);
            app.output_img.hdr = hdr; 
            uialert(app.UIFigure,"FCM segmentation is complete, please check the cluster" + ...
                " centre to decide whether to save the image, to save the image click the button'save output'.",'Notice','Icon','success')
            
            
        end

        % Button pushed function: saveoutputButton
        function saveoutputButtonPushed(app, event)
            path=[app.output_path,'\',app.output_prefix,'.nii.gz'];
            save_nii(app.output_img, path);
            path=[app.output_path,'\',app.output_prefix,'_membership.mat'];
            membership=app.membership_mat;
            save(path,'membership');
            path=[app.output_path,'\',app.output_prefix,'_radio_W.mat'];
            radio_W=app.W_mat;
            save(path,'radio_W');
            uialert(app.UIFigure,'The image,radio_W and the membership matrix are saved and can be viewed in the corresponding path.','Notice','Icon','success')
            
        end

        % Button pushed function: saveallButton
        function saveallButtonPushed(app, event)
            app.m_value=app.FCMparammEditField.Value;
            app.output_prefix=app.filename_prefix_EditField.Value;        
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success')
                
        end

        % Selection changed function: imageButtonGroup_2
        function imageButtonGroup_2SelectionChanged(app, event)
            selectedButton = app.imageButtonGroup_2.SelectedObject;
            switch selectedButton.Text
                case 'path of input images'
                    app.input_dir_path = uigetdir('C:\Users\86133\Documents\MATLAB');
                    app.image_to_fuzzy_C_means_EditField_2.Value=app.input_dir_path;
                case 'path of save images'
                    app.output_path = uigetdir('C:\Users\86133\Documents\MATLAB');
                    app.path_of_save_image_EditField.Value=app.output_path;
               
            end
        end

        % Button pushed function: saveallButton_2
        function saveallButton_2Pushed(app, event)
            app.m_value=app.FCMparammEditField_2.Value;
            app.output_prefix=app.filename_prefix_EditField_2.Value;        
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success')
        end

        % Button pushed function: startandsaveButton
        function startandsaveButtonPushed(app, event)
            file_list=dir(app.input_dir_path);
            %进度条显示处理进度
            progress_bar=uiprogressdlg(app.UIFigure,'Title','Please wait','Message','Working on image 1','Value',0);
            for i=1:length(file_list)%循环处理图像
                if i==1%跳过前两个空文件夹
                    continue
                elseif i==2
                    continue
                end
                file_name=fullfile(app.input_dir_path,file_list(i).name);
                info=load_untouch_nii(file_name);
                IMG=info.img;
                hdr=info.hdr;
    %             K-means定初始聚类中心
                app.k_value=4;
%                 [C_init] = kmeans_init(IMG,app.k_value,[],[]);
                img=int32(IMG(IMG~=0));
    %             FCM
                [C,U,LUT,H]=FastFCMeans(img(:),app.k_value,app.m_value,false) ;% perform FCM segmentation
                disp('FCM segmentation centers are:' );
                disp(C);
                app.membership_mat=U;
                center='';
                for ii=1:length(C)
                    center=[center,num2str(C(ii))];
                    if ii~=length(C)
                        center=[center,','];
                    end
                end
                app.FCMsegmentationcentersEditField_2.Value=center;
                L=LUT2label(img,LUT);
                % Get FCM results获得FCM处理后的结果
                FCM_seg=zeros(size(IMG));
                OriginId=find(IMG~=0);
                FCM_seg(OriginId(L(:)==app.k_value))=1;
                if app.k_value>3
                    FCM_seg(OriginId(L(:)==app.k_value-1))=1;
                end
                 W = zeros(1,K);
                for c = 1:4
                    W(c) = length(find(L(:) == c)) / numel(IMG(IMG ~= 0));
                end
                app.W_mat=W;
                app.output_img=make_nii(FCM_seg);
                app.output_img.hdr = hdr; 
                
                path=[app.output_path,'\',app.output_prefix,'.nii.gz'];
                save_nii(app.output_img, path);
                path=[app.output_path,'\',app.output_prefix,'_membership.mat'];
                membership=app.membership_mat;
                save(path,'membership');
                path=[app.output_path,'\',app.output_prefix,'_radio_W.mat'];
                radio_W=app.W_mat;
                save(path,'radio_W');
%                 uialert(app.UIFigure,"FCM segmentation is complete, please check the cluster" + ...
%                     " centre to decide whether to save the image, to save the image click the button'save output'.",'Notice','Icon','success')
                
                progress_bar.Value=(i-2)/(length(file_list)-2);
                if i~=length(file_list)
                    progress_bar.Message=sprintf('Working on image %d',i-1);
                else%关闭进度条
                    close(progress_bar);
                end
            end
            uialert(app.UIFigure,['Histogram matching is complete,' ...
                'and the output images are saved to the corresponding path.'],'Notice','Icon','success')
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.helponfastfuzzyCmeansPanel = uipanel(app.UIFigure);
            app.helponfastfuzzyCmeansPanel.Title = 'help on fast fuzzy C-means';
            app.TextAreaLabel = uilabel(app.helponfastfuzzyCmeansPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.helponfastfuzzyCmeansPanel);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleimageprocessingTab = uitab(app.TabGroup);
            app.SingleimageprocessingTab.Title = 'Single image processing';
            app.currentmodulefastfuzzyCmeansPanel = uipanel(app.SingleimageprocessingTab);
            app.currentmodulefastfuzzyCmeansPanel.Title = 'current module:fast fuzzy C-means';
            app.imageButtonGroup = uibuttongroup(app.currentmodulefastfuzzyCmeansPanel);
            app.imageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @imageButtonGroupSelectionChanged, true);
            app.imageButtonGroup.Title = 'image';
            app.imagetofuzzyCmeansButton = uitogglebutton(app.imageButtonGroup);
            app.imagetofuzzyCmeansButton.Text = 'image to fuzzy C-means';
            app.imagetofuzzyCmeansButton.Value = true;
            app.image_to_fuzzy_C_means_EditField = uieditfield(app.imageButtonGroup, 'text');
            app.pathofsaveimageButton = uitogglebutton(app.imageButtonGroup);
            app.pathofsaveimageButton.Text = 'path of save image';
            app.path_of_save_image_EditField = uieditfield(app.imageButtonGroup, 'text');
            app.startButton = uibutton(app.currentmodulefastfuzzyCmeansPanel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.saveoutputButton = uibutton(app.currentmodulefastfuzzyCmeansPanel, 'push');
            app.saveoutputButton.ButtonPushedFcn = createCallbackFcn(app, @saveoutputButtonPushed, true);
            app.saveoutputButton.Text = 'save output';
            app.FCMresultPanel = uipanel(app.currentmodulefastfuzzyCmeansPanel);
            app.FCMresultPanel.Title = 'FCM result';
            app.FCMsegmentationcentersEditFieldLabel = uilabel(app.FCMresultPanel);
            app.FCMsegmentationcentersEditFieldLabel.Text = 'FCM segmentation centers';
            app.FCMsegmentationcentersEditField = uieditfield(app.FCMresultPanel, 'text');
            app.saveparametersPanel = uipanel(app.currentmodulefastfuzzyCmeansPanel);
            app.saveparametersPanel.Title = 'save parameters';
            app.FCMparammEditFieldLabel = uilabel(app.saveparametersPanel);
            app.FCMparammEditFieldLabel.Text = 'FCM param:m     ';
            app.FCMparammEditField = uieditfield(app.saveparametersPanel, 'numeric');
            app.saveallButton = uibutton(app.saveparametersPanel, 'push');
            app.saveallButton.ButtonPushedFcn = createCallbackFcn(app, @saveallButtonPushed, true);
            app.saveallButton.Text = 'save all';
            app.outputfilenameEditFieldLabel = uilabel(app.saveparametersPanel);
            app.outputfilenameEditFieldLabel.Text = 'output filename';
            app.filename_prefix_EditField = uieditfield(app.saveparametersPanel, 'text');
            app.BatchimageprocessingTab = uitab(app.TabGroup);
            app.BatchimageprocessingTab.Title = 'Batch image processing';
            app.currentmodulefastfuzzyCmeansPanel_2 = uipanel(app.BatchimageprocessingTab);
            app.currentmodulefastfuzzyCmeansPanel_2.Title = 'current module:fast fuzzy C-means';
            app.imageButtonGroup_2 = uibuttongroup(app.currentmodulefastfuzzyCmeansPanel_2);
            app.imageButtonGroup_2.SelectionChangedFcn = createCallbackFcn(app, @imageButtonGroup_2SelectionChanged, true);
            app.imageButtonGroup_2.Title = 'image';
            app.pathofinputimagesButton = uitogglebutton(app.imageButtonGroup_2);
            app.pathofinputimagesButton.Text = 'path of input images';
            app.pathofinputimagesButton.Value = true;
            app.image_to_fuzzy_C_means_EditField_2 = uieditfield(app.imageButtonGroup_2, 'text');
            app.pathofsaveimagesButton = uitogglebutton(app.imageButtonGroup_2);
            app.pathofsaveimagesButton.Text = 'path of save images';
            app.path_of_save_image_EditField_2 = uieditfield(app.imageButtonGroup_2, 'text');
            app.startandsaveButton = uibutton(app.currentmodulefastfuzzyCmeansPanel_2, 'push');
            app.startandsaveButton.ButtonPushedFcn = createCallbackFcn(app, @startandsaveButtonPushed, true);
            app.startandsaveButton.Text = 'start and save';
            app.FCMresultPanel_2 = uipanel(app.currentmodulefastfuzzyCmeansPanel_2);
            app.FCMresultPanel_2.Title = 'FCM result';
            app.FCMsegmentationcentersEditField_2Label = uilabel(app.FCMresultPanel_2);
            app.FCMsegmentationcentersEditField_2Label.Text = 'FCM segmentation centers';
            app.FCMsegmentationcentersEditField_2 = uieditfield(app.FCMresultPanel_2, 'text');
            app.saveparametersPanel_2 = uipanel(app.currentmodulefastfuzzyCmeansPanel_2);
            app.saveparametersPanel_2.Title = 'save parameters';
            app.FCMparammEditField_2Label = uilabel(app.saveparametersPanel_2);
            app.FCMparammEditField_2Label.Text = 'FCM param:m     ';
            app.FCMparammEditField_2 = uieditfield(app.saveparametersPanel_2, 'numeric');
            app.saveallButton_2 = uibutton(app.saveparametersPanel_2, 'push');
            app.saveallButton_2.ButtonPushedFcn = createCallbackFcn(app, @saveallButton_2Pushed, true);
            app.saveallButton_2.Text = 'save all';
            app.outputprefixLabel = uilabel(app.saveparametersPanel_2);
            app.outputprefixLabel.Text = 'output prefix';
            app.filename_prefix_EditField_2 = uieditfield(app.saveparametersPanel_2, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FCM_parameter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end