classdef HD_BET_parameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        EditMenu                      matlab.ui.container.Menu
        helponskullstrippingPanel     matlab.ui.container.Panel
        TextAreaLabel                 matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        TabGroup                      matlab.ui.container.TabGroup
        SingleimageprocessingTab      matlab.ui.container.Tab
        currentmoduleskullstrippingPanel  matlab.ui.container.Panel
        imageButtonGroup              matlab.ui.container.ButtonGroup
        imagetoskullstrippingButton   matlab.ui.control.ToggleButton
        image_to_skull_stripping_EditField  matlab.ui.control.EditField
        pathofsaveimageButton         matlab.ui.control.ToggleButton
        path_of_save_image_EditField  matlab.ui.control.EditField
        startButton                   matlab.ui.control.Button
        saveparametersPanel           matlab.ui.container.Panel
        saveallButton                 matlab.ui.control.Button
        outputprefixLabel             matlab.ui.control.Label
        filename_prefix_EditField     matlab.ui.control.EditField
        save_maskDropDownLabel        matlab.ui.control.Label
        save_maskDropDown             matlab.ui.control.DropDown
        BatchimageprocessingTab       matlab.ui.container.Tab
        currentmoduleskullstrippingPanel_2  matlab.ui.container.Panel
        startButton_2                 matlab.ui.control.Button
        parametersPanel               matlab.ui.container.Panel
        pathofinputimagesButton       matlab.ui.control.Button
        image_to_bet_EditField        matlab.ui.control.EditField
        inputimageprefixLabel         matlab.ui.control.Label
        filename_prefix_EditField_4   matlab.ui.control.EditField
        path_of_input_images_Label    matlab.ui.control.Label
        input_name_Label              matlab.ui.control.Label
        output_prefix_Label           matlab.ui.control.Label
        traget_Label                  matlab.ui.control.Label
        outputprefixLabel_2           matlab.ui.control.Label
        filename_prefix_EditField_5   matlab.ui.control.EditField
        save_maskDropDown_2Label      matlab.ui.control.Label
        save_maskDropDown_2           matlab.ui.control.DropDown
        saveallButton_2               matlab.ui.control.Button
    end

    
    properties (Access = private)
        Calling_app % 回调主APP
        input_img_path
        output_img
        output_prefix
        output_path
        
        is_save_mask
        %batch
        input_dir_path
        inputs_prefix


    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.Calling_app=mainapp;
            app.filename_prefix_EditField.Value="Please enter the prefix of the saved file";         
        end

        % Selection changed function: imageButtonGroup
        function imageButtonGroupSelectionChanged(app, event)
            selectedButton = app.imageButtonGroup.SelectedObject;
%             app.k_means_param_EditField.Value="请输入保存文件的前缀,后点击'filename prefix'";
            switch selectedButton.Text
                case 'image to skull stripping'
                    [filename,path]=uigetfile({'*.nii.gz;*.nii'},'选择图片文件');
                    app.image_to_skull_stripping_EditField.Value=[path,filename];
                    app.input_img_path=app.image_to_skull_stripping_EditField.Value;
                case 'path of save image'
                    app.output_path = uigetdir();
                    app.path_of_save_image_EditField.Value=app.output_path;
               
            end
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            device=py.str('cpu');
            mode=py.str('fast');
            pp=py.int(1);
            overwrite_existing=py.int(1);
            input_path=py.str(app.input_img_path);
            app.output_img=fullfile(app.output_path,[app.output_prefix,'.nii.gz']);
            app.output_img=py.str(app.output_img);
            py.HD_BET_start.HD_BET_run(input_path,app.output_img,mode,device,tta,app.is_save_mask,overwrite_existing,pp);
% 弹出结束提示
            switch app.save_maskDropDown.Value
                case 'yes'
                    uialert(app.UIFigure,"Skull stripping is complete and the brain image and mask are saved in the folder!",'Notice','Icon','success')
                case 'no'
                    uialert(app.UIFigure,"Skull stripping is complete and the brain image is saved in the folder!",'Notice','Icon','success')
            end 
             
            
        end

        % Button pushed function: saveallButton
        function saveallButtonPushed(app, event)
            app.output_prefix=app.filename_prefix_EditField.Value;   
            value = app.save_maskDropDown.Value;
            switch value
                case 'yes'
                    app.is_save_mask=py.int(1);
                case 'no'
                    app.is_save_mask=py.int(0);
            end
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success')
                
        end

        % Value changed function: save_maskDropDown
        function save_maskDropDownValueChanged(app, event)
            value = app.save_maskDropDown.Value;
            switch value
                case 'yes'
                    app.is_save_mask=py.int(1);
                case 'no'
                    app.is_save_mask=py.int(0);
            end
        end

        % Button pushed function: saveallButton_2
        function saveallButton_2Pushed(app, event)
            app.output_prefix=app.filename_prefix_EditField_5.Value; 
            app.inputs_prefix=app.filename_prefix_EditField_4.Value;%非正则表达式
            app.inputs_prefix=regexptranslate("escape",app.inputs_prefix);%转成正则表达式

            value = app.save_maskDropDown_2.Value;
            switch value
                case 'yes'
                    app.is_save_mask=py.int(1);
                case 'no'
                    app.is_save_mask=py.int(0);
            end
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success')
        end

        % Button pushed function: startButton_2
        function startButton_2Pushed(app, event)
            device=py.str('cpu');
            mode=py.str('fast');
            pp=py.int(1);
            overwrite_existing=py.int(1);
            [files_name,files_path]=file_extraction(app.input_dir_path, app.inputs_prefix);
            if isempty(files_name)
                alart_message='There is not the images to be processed in the document!';
                uialert(app.UIFigure,alart_message,'Notice','Icon','error') 
            else
                %进度条显示处理进度
                progress_bar=uiprogressdlg(app.UIFigure,'Title','Please wait','Message','Working on image 1','Value',0);
                for i=1:length(files_name)
                    file=files_name{i};
                    input_path=py.str(file);
                    [~, endindex]= regexp(file,app.inputs_prefix);
                    suffix = file((endindex+1):end);
                    save_path=fullfile(files_path{i},[app.output_prefix,suffix]);
                    save_path=py.str(save_path);
                    
                    py.HD_BET_start.HD_BET_run(input_path,save_path,mode,device,tta,app.is_save_mask,overwrite_existing,pp);


                    %进度条更新
                    progress_bar.Value=i/length(files_name);
                    if i~=length(files_name)
                        progress_bar.Message=sprintf('Working on image %d',i+1);
                    else%关闭进度条
                        close(progress_bar);
                    end
                end
            end
            
            uialert(app.UIFigure,['Skull stripping is complete,' ...
                'and the output images are saved to the corresponding path.'],'Notice','Icon','success')
        
        end

        % Button pushed function: pathofinputimagesButton
        function pathofinputimagesButtonPushed(app, event)
            app.input_dir_path = uigetdir();
            app.image_to_bet_EditField.Value=app.input_dir_path;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.helponskullstrippingPanel = uipanel(app.UIFigure);
            app.helponskullstrippingPanel.Title = 'help on skull-stripping';
            app.TextAreaLabel = uilabel(app.helponskullstrippingPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.helponskullstrippingPanel);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleimageprocessingTab = uitab(app.TabGroup);
            app.SingleimageprocessingTab.Title = 'Single image processing';
            app.currentmoduleskullstrippingPanel = uipanel(app.SingleimageprocessingTab);
            app.currentmoduleskullstrippingPanel.Title = 'current module:skull-stripping';
            app.imageButtonGroup = uibuttongroup(app.currentmoduleskullstrippingPanel);
            app.imageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @imageButtonGroupSelectionChanged, true);
            app.imageButtonGroup.Title = 'image';
            app.imagetoskullstrippingButton = uitogglebutton(app.imageButtonGroup);
            app.imagetoskullstrippingButton.Text = 'image to skull stripping';
            app.image_to_skull_stripping_EditField = uieditfield(app.imageButtonGroup, 'text');
            app.pathofsaveimageButton = uitogglebutton(app.imageButtonGroup);
            app.pathofsaveimageButton.Text = 'path of save image';
            app.pathofsaveimageButton.Value = true;
            app.path_of_save_image_EditField = uieditfield(app.imageButtonGroup, 'text');
            app.startButton = uibutton(app.currentmoduleskullstrippingPanel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.saveparametersPanel = uipanel(app.currentmoduleskullstrippingPanel);
            app.saveparametersPanel.Title = 'save parameters';
            app.saveallButton = uibutton(app.saveparametersPanel, 'push');
            app.saveallButton.ButtonPushedFcn = createCallbackFcn(app, @saveallButtonPushed, true);
            app.saveallButton.Text = 'save all';
            app.outputprefixLabel = uilabel(app.saveparametersPanel);
            app.outputprefixLabel.Text = 'output prefix';
            app.filename_prefix_EditField = uieditfield(app.saveparametersPanel, 'text');
            app.save_maskDropDownLabel = uilabel(app.saveparametersPanel);
            app.save_maskDropDownLabel.Text = 'save_mask';
            app.save_maskDropDown = uidropdown(app.saveparametersPanel);
            app.save_maskDropDown.Items = {'yes', 'no'};
            app.save_maskDropDown.ValueChangedFcn = createCallbackFcn(app, @save_maskDropDownValueChanged, true);
            app.save_maskDropDown.Value = 'yes';
            app.BatchimageprocessingTab = uitab(app.TabGroup);
            app.BatchimageprocessingTab.Title = 'Batch image processing';
            app.currentmoduleskullstrippingPanel_2 = uipanel(app.BatchimageprocessingTab);
            app.currentmoduleskullstrippingPanel_2.Title = 'current module:skull-stripping';
            app.startButton_2 = uibutton(app.currentmoduleskullstrippingPanel_2, 'push');
            app.startButton_2.ButtonPushedFcn = createCallbackFcn(app, @startButton_2Pushed, true);
            app.startButton_2.Text = 'start';
            app.parametersPanel = uipanel(app.currentmoduleskullstrippingPanel_2);
            app.parametersPanel.Title = 'parameters';
            app.pathofinputimagesButton = uibutton(app.parametersPanel, 'push');
            app.pathofinputimagesButton.ButtonPushedFcn = createCallbackFcn(app, @pathofinputimagesButtonPushed, true);
            app.pathofinputimagesButton.Text = 'path of input images';
            app.image_to_bet_EditField = uieditfield(app.parametersPanel, 'text');
            app.inputimageprefixLabel = uilabel(app.parametersPanel);
            app.inputimageprefixLabel.Text = 'input image prefix';
            app.filename_prefix_EditField_4 = uieditfield(app.parametersPanel, 'text');
            app.path_of_input_images_Label = uilabel(app.parametersPanel);
            app.path_of_input_images_Label.Text = '选择批量图片集所在的大文件夹，图片集以子文件夹形式划分';
            app.input_name_Label = uilabel(app.parametersPanel);
            app.input_name_Label.Text = '待批量处理的图片文件名前缀';
            app.output_prefix_Label = uilabel(app.parametersPanel);
            app.output_prefix_Label.Text = '相关输出图像的前缀名，存放在输入图片所在的子文件夹';
            app.traget_Label = uilabel(app.parametersPanel);
            app.traget_Label.Text = '设置是否保存mask';
            app.outputprefixLabel_2 = uilabel(app.parametersPanel);
            app.outputprefixLabel_2.Text = 'output prefix';
            app.filename_prefix_EditField_5 = uieditfield(app.parametersPanel, 'text');
            app.save_maskDropDown_2Label = uilabel(app.parametersPanel);
            app.save_maskDropDown_2Label.Text = 'save_mask';
            app.save_maskDropDown_2 = uidropdown(app.parametersPanel);
            app.save_maskDropDown_2.Items = {'yes', 'no'};
            app.save_maskDropDown_2.Value = 'yes';
            app.saveallButton_2 = uibutton(app.parametersPanel, 'push');
            app.saveallButton_2.ButtonPushedFcn = createCallbackFcn(app, @saveallButton_2Pushed, true);
            app.saveallButton_2.Text = 'save all';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HD_BET_parameter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end