classdef MRF_parameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        EditMenu                      matlab.ui.container.Menu
        helponfastfuzzyCmeansPanel    matlab.ui.container.Panel
        TextAreaLabel                 matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        TabGroup                      matlab.ui.container.TabGroup
        SingleimageprocessingTab      matlab.ui.container.Tab
        currentmoduleMarkovRandomFieldPanel  matlab.ui.container.Panel
        imageButtonGroup              matlab.ui.container.ButtonGroup
        FCMsegmentationresultButton   matlab.ui.control.ToggleButton
        FCM_result_EditField          matlab.ui.control.EditField
        pathofsaveimageButton         matlab.ui.control.ToggleButton
        vessel_enhance_result_EditField  matlab.ui.control.EditField
        vesselenhanceresultButton     matlab.ui.control.ToggleButton
        path_of_save_image_EditField  matlab.ui.control.EditField
        histogrammatchedimageButton   matlab.ui.control.ToggleButton
        histogram_matched_image_EditField  matlab.ui.control.EditField
        startButton                   matlab.ui.control.Button
        saveparametersPanel           matlab.ui.container.Panel
        saveallButton                 matlab.ui.control.Button
        outputfilenameEditFieldLabel  matlab.ui.control.Label
        filename_prefix_EditField     matlab.ui.control.EditField
        matofclassmembershipsButton   matlab.ui.control.Button
        matofradioWButton             matlab.ui.control.Button
        U_EditField                   matlab.ui.control.EditField
        W_EditField                   matlab.ui.control.EditField
        BatchimageprocessingTab       matlab.ui.container.Tab
        currentmoduleMarkovRandomFieldPanel_2  matlab.ui.container.Panel
        startButton_2                 matlab.ui.control.Button
        parametersPanel               matlab.ui.container.Panel
        pathofinputimagesButton       matlab.ui.control.Button
        image_to_MRF_EditField        matlab.ui.control.EditField
        FCMresultprefixLabel          matlab.ui.control.Label
        FCM_prefix_EditField_3        matlab.ui.control.EditField
        path_of_input_images_Label    matlab.ui.control.Label
        input_name_Label              matlab.ui.control.Label
        output_prefix_Label           matlab.ui.control.Label
        outputprefixLabel             matlab.ui.control.Label
        filename_prefix_EditField_4   matlab.ui.control.EditField
        saveallButton_2               matlab.ui.control.Button
        input_name_Label_2            matlab.ui.control.Label
        VesselenhanceprefixLabel      matlab.ui.control.Label
        VE_prefix_EditField_5         matlab.ui.control.EditField
        input_name_Label_3            matlab.ui.control.Label
        HistmatchedimagesprefixLabel  matlab.ui.control.Label
        matched_prefix_EditField_6    matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Calling_app % 回调主APP
        FCM_result_path
        VE_result_path
        output_img
        output_prefix
        output_path
        input_img_path
        membership_mat
        W_mat
        
        %batch
        FCM_result_prefix
        VE_result_prefix
        Matched_prefix
        input_dir_path
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.Calling_app=mainapp;
            app.filename_prefix_EditField.Value="Please enter the prefix of the saved file";       
        end

        % Selection changed function: imageButtonGroup
        function imageButtonGroupSelectionChanged(app, event)
            selectedButton = app.imageButtonGroup.SelectedObject;
%             app.k_means_param_EditField.Value="请输入保存文件的前缀,后点击'filename prefix'";
            switch selectedButton.Text
                case 'FCM segmentation result'
                    [filename,path]=uigetfile({'*.nii.gz;*.nii'},'选择图片文件');
                    app.FCM_result_EditField.Value=[path,filename];
                    app.FCM_result_path=app.FCM_result_EditField.Value;
                case 'vessel enhance result'
                    [filename,path]=uigetfile({'*.nii.gz;*.nii'},'选择图片文件');
                    app.vessel_enhance_result_EditField.Value=[path,filename];
                    app.VE_result_path=app.vessel_enhance_result_EditField.Value;
                case 'path of save image'
                    app.output_path = uigetdir();
                    app.path_of_save_image_EditField.Value=app.output_path;
                case 'histogram matched image'
                    [filename,path]=uigetfile({'*.nii.gz;*.nii'},'选择图片文件');
                    app.histogram_matched_image_EditField.Value=[path,filename];
                    app.input_img_path=app.histogram_matched_image_EditField.Value;
            end
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            info_FCM=load_untouch_nii(app.FCM_result_path);
            FCM_seg=info_FCM.img;
            FCM_hdr=info_FCM.hdr;
            info_VE=load_untouch_nii(app.VE_result_path);
            vesselness=info_VE.img;
            VE_hdr=info_VE.hdr;
            info=load_untouch_nii(app.input_img_path);
            IMG=info.img;
            hdr=info.hdr;
            
            beta = 0.2;
            IL = 10;
            [MRF_seg] = MRF(FCM_seg, IMG, vesselness, app.membership_mat, beta, app.W_mat, IL);
            
            %% remove little islands
            [MRF_seg_refine, ~, ~] = Connection_Judge_3D(MRF_seg, 0, [], 20, 3);

            app.output_img=make_nii(MRF_seg_refine);
            app.output_img.hdr = hdr; 
            path=fullfile(app.output_path,[app.output_prefix,'.nii.gz']);
            save_nii(app.output_img, path);
            uialert(app.UIFigure,"MRF segmentation is complete, The image " + ...
                "is saved and can be viewed in the corresponding path.",'Notice','Icon','success')
            
            
        end

        % Button pushed function: saveallButton
        function saveallButtonPushed(app, event)

            app.output_prefix=app.filename_prefix_EditField.Value;        
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success')
                
        end

        % Button pushed function: saveallButton_2
        function saveallButton_2Pushed(app, event)
            app.FCM_result_prefix=app.FCM_prefix_EditField_3.Value;
            app.FCM_result_prefix=regexptranslate("escape",app.FCM_result_prefix);%转成正则表达式
            app.VE_result_prefix=app.VE_prefix_EditField_5.Value;
            app.VE_result_prefix=regexptranslate("escape",app.VE_result_prefix);
            app.Matched_prefix=app.matched_prefix_EditField_6.Value;
            app.Matched_prefix=regexptranslate("escape",app.Matched_prefix);
            app.output_prefix=app.filename_prefix_EditField_4.Value;        
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success')
        end

        % Button pushed function: startButton_2
        function startButton_2Pushed(app, event)
            
            [Matched_files_name,Matched_files_path]=file_extraction(app.input_dir_path, app.Matched_prefix);
            [VE_files_name,VE_files_path]=file_extraction(app.input_dir_path, app.VE_result_prefix);
            %FCM前缀会读取到.nii.gz/.membership.mat/.radio_W.mat
            [FCM_files_name,FCM_files_path]=file_extraction(app.input_dir_path, app.FCM_result_prefix);
            FCM_images_name={};
            FCM_images_path={};
            FCM_membership_name={};
            FCM_membership_path={};
            FCM_W_name={};
            FCM_W_path={};
            for ii=1:length(FCM_files_name)
                if endsWith(FCM_files_name{ii},'.nii.gz')
                   FCM_images_name=[FCM_images_name;FCM_files_name{ii}];
                   FCM_images_path=[FCM_images_path;FCM_files_path{ii}];   
                elseif endsWith(FCM_files_name{ii},'_membership.mat')
                   prefix=FCM_images_name{end}(1:end-7);
                   %确定membership和image是一一对应关系
                   if strcmp(prefix,FCM_files_name{ii}(1:end-15)) && length(FCM_images_name)==length(FCM_membership_name)+1
                       FCM_membership_name=[FCM_membership_name;FCM_files_name{ii}];
                       FCM_membership_path=[FCM_membership_path;FCM_files_path{ii}]; 
                   else
                       alart_message=[FCM_images_name{end},' have not the corresponding membership matrix!'];
                       uialert(app.UIFigure,alart_message,'Notice','Icon','error') 
                   end
                elseif endsWith(FCM_files_name{ii},'_radio_W.mat')
                   prefix=FCM_images_name{end}(1:end-7);
                   %确定membership和image是一一对应关系
                   if strcmp(prefix,FCM_files_name{ii}(1:end-12)) && length(FCM_images_name)==length(FCM_W_name)+1
                       FCM_W_name=[FCM_W_name;FCM_files_name{ii}];
                       FCM_W_path=[FCM_W_path;FCM_files_path{ii}];
                   else
                       alart_message=[FCM_images_name{end},' have not the corresponding membership matrix!'];
                       uialert(app.UIFigure,alart_message,'Notice','Icon','error') 
                   end
                else
                    alart_message="There is the files can't be used!";
                    uialert(app.UIFigure,alart_message,'Notice','Icon','error') 
                end                   
            end
            
            
            % 开始批量处理
            if isempty(Matched_files_name)||isempty(VE_files_name)||isempty(FCM_images_name)
                alart_message='There is not the images to be processed in the document!';
                uialert(app.UIFigure,alart_message,'Notice','Icon','error') 
            else
                if length(Matched_files_name)~=length(VE_files_name)||...
                        length(FCM_images_name)~=length(VE_files_name)||...
                        length(FCM_images_name)~=length(Matched_files_name)
                    alart_message=['The number of images to be processed in FCM_images,' ...
                        'Vessel_enhance_images,Matched_images is not the same!'];
                    uialert(app.UIFigure,alart_message,'Notice','Icon','error') 
                end
                %进度条显示处理进度
                progress_bar=uiprogressdlg(app.UIFigure,'Title','Please wait','Message','Working on image 1','Value',0);
                for i=1:length(Matched_files_name)
                    match_file=Matched_files_name{i};
                    info=load_untouch_nii(match_file);
                    IMG=info.img;
                    hdr=info.hdr;
                    info_FCM=load_untouch_nii(FCM_images_name{i});
                    FCM_seg=info_FCM.img;
                    info_VE=load_untouch_nii(VE_files_name{i});
                    vesselness=info_VE.img;
                    
                    membership=importdata(FCM_membership_name{i});
                    W=importdata(FCM_W_name{i});

                    
                    beta = 0.2;
                    IL = 10;
                    [MRF_seg] = MRF(FCM_seg, IMG, vesselness, membership, beta, W, IL);
                    
                    %% remove little islands
                    [MRF_seg_refine, ~, ~] = Connection_Judge_3D(MRF_seg, 0, [], 20, 3);
        
                    app.output_img=make_nii(MRF_seg_refine);
                    app.output_img.hdr = hdr; 
                    
                    [~, endindex]= regexp(match_file,app.Matched_prefix);
                    disp(match_file)
                    disp(endindex)
                    suffix = match_file(endindex+1:end);
                    save_path=fullfile(Matched_files_path{i},[app.output_prefix,suffix]);
                    save_nii(app.output_img, save_path);

                    %进度条更新
                    progress_bar.Value=i/length(Matched_files_name);
                    if i~=length(Matched_files_name)
                        progress_bar.Message=sprintf('Working on image %d',i+1);
                    else%关闭进度条
                        close(progress_bar);
                    end
                end
            end

            uialert(app.UIFigure,['Markov Random Field is complete,' ...
                'and the output images are saved to the corresponding path.'],'Notice','Icon','success')
        
        end

        % Button pushed function: matofclassmembershipsButton
        function matofclassmembershipsButtonPushed(app, event)
            [filename,path]=uigetfile({'*.mat'},'选择mat文件');
            app.U_EditField.Value=[path,filename];
            app.membership_mat=importdata(app.U_EditField.Value);    
        end

        % Button pushed function: matofradioWButton
        function matofradioWButtonPushed(app, event)
            [filename,path]=uigetfile({'*.mat'},'选择mat文件');
            app.W_EditField.Value=[path,filename];
            app.W_mat=importdata(app.W_EditField.Value);  
        end

        % Button pushed function: pathofinputimagesButton
        function pathofinputimagesButtonPushed(app, event)
            app.input_dir_path = uigetdir();
            app.image_to_MRF_EditField.Value=app.input_dir_path;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.helponfastfuzzyCmeansPanel = uipanel(app.UIFigure);
            app.helponfastfuzzyCmeansPanel.Title = 'help on fast fuzzy C-means';
            app.TextAreaLabel = uilabel(app.helponfastfuzzyCmeansPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.helponfastfuzzyCmeansPanel);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleimageprocessingTab = uitab(app.TabGroup);
            app.SingleimageprocessingTab.Title = 'Single image processing';
            app.currentmoduleMarkovRandomFieldPanel = uipanel(app.SingleimageprocessingTab);
            app.currentmoduleMarkovRandomFieldPanel.Title = 'current module:Markov Random Field';
            app.imageButtonGroup = uibuttongroup(app.currentmoduleMarkovRandomFieldPanel);
            app.imageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @imageButtonGroupSelectionChanged, true);
            app.imageButtonGroup.Title = 'image';
            app.FCMsegmentationresultButton = uitogglebutton(app.imageButtonGroup);
            app.FCMsegmentationresultButton.Text = 'FCM segmentation result';
            app.FCM_result_EditField = uieditfield(app.imageButtonGroup, 'text');
            app.pathofsaveimageButton = uitogglebutton(app.imageButtonGroup);
            app.pathofsaveimageButton.Text = 'path of save image';
            app.pathofsaveimageButton.Value = true;
            app.vessel_enhance_result_EditField = uieditfield(app.imageButtonGroup, 'text');
            app.vesselenhanceresultButton = uitogglebutton(app.imageButtonGroup);
            app.vesselenhanceresultButton.Text = 'vessel enhance result';
            app.path_of_save_image_EditField = uieditfield(app.imageButtonGroup, 'text');
            app.histogrammatchedimageButton = uitogglebutton(app.imageButtonGroup);
            app.histogrammatchedimageButton.Text = 'histogram matched image';
            app.histogram_matched_image_EditField = uieditfield(app.imageButtonGroup, 'text');
            app.startButton = uibutton(app.currentmoduleMarkovRandomFieldPanel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.saveparametersPanel = uipanel(app.currentmoduleMarkovRandomFieldPanel);
            app.saveparametersPanel.Title = 'save parameters';
            app.saveallButton = uibutton(app.saveparametersPanel, 'push');
            app.saveallButton.ButtonPushedFcn = createCallbackFcn(app, @saveallButtonPushed, true);
            app.saveallButton.Text = 'save all';
            app.outputfilenameEditFieldLabel = uilabel(app.saveparametersPanel);
            app.outputfilenameEditFieldLabel.Text = 'output filename';
            app.filename_prefix_EditField = uieditfield(app.saveparametersPanel, 'text');
            app.matofclassmembershipsButton = uibutton(app.saveparametersPanel, 'push');
            app.matofclassmembershipsButton.ButtonPushedFcn = createCallbackFcn(app, @matofclassmembershipsButtonPushed, true);
            app.matofclassmembershipsButton.Text = 'mat of class memberships';
            app.matofradioWButton = uibutton(app.saveparametersPanel, 'push');
            app.matofradioWButton.ButtonPushedFcn = createCallbackFcn(app, @matofradioWButtonPushed, true);
            app.matofradioWButton.Text = 'mat of radio W';
            app.U_EditField = uieditfield(app.saveparametersPanel, 'text');
            app.W_EditField = uieditfield(app.saveparametersPanel, 'text');
            app.BatchimageprocessingTab = uitab(app.TabGroup);
            app.BatchimageprocessingTab.Title = 'Batch image processing';
            app.currentmoduleMarkovRandomFieldPanel_2 = uipanel(app.BatchimageprocessingTab);
            app.currentmoduleMarkovRandomFieldPanel_2.Title = 'current module:Markov Random Field';
            app.startButton_2 = uibutton(app.currentmoduleMarkovRandomFieldPanel_2, 'push');
            app.startButton_2.ButtonPushedFcn = createCallbackFcn(app, @startButton_2Pushed, true);
            app.startButton_2.Text = 'start';
            app.parametersPanel = uipanel(app.currentmoduleMarkovRandomFieldPanel_2);
            app.parametersPanel.Title = 'parameters';
            app.pathofinputimagesButton = uibutton(app.parametersPanel, 'push');
            app.pathofinputimagesButton.ButtonPushedFcn = createCallbackFcn(app, @pathofinputimagesButtonPushed, true);
            app.pathofinputimagesButton.Text = 'path of input images';
            app.image_to_MRF_EditField = uieditfield(app.parametersPanel, 'text');
            app.FCMresultprefixLabel = uilabel(app.parametersPanel);
            app.FCMresultprefixLabel.Text = 'FCM result prefix';
            app.FCM_prefix_EditField_3 = uieditfield(app.parametersPanel, 'text');
            app.path_of_input_images_Label = uilabel(app.parametersPanel);
            app.path_of_input_images_Label.Text = '选择批量图片集所在的大文件夹，图片集以子文件夹形式划分';
            app.input_name_Label = uilabel(app.parametersPanel);
            app.input_name_Label.Text = 'FCM的结果文件名前缀';
            app.output_prefix_Label = uilabel(app.parametersPanel);
            app.output_prefix_Label.Text = '相关输出图像的前缀名，存放在输入图片所在的子文件夹';
            app.outputprefixLabel = uilabel(app.parametersPanel);
            app.outputprefixLabel.Text = 'output prefix';
            app.filename_prefix_EditField_4 = uieditfield(app.parametersPanel, 'text');
            app.saveallButton_2 = uibutton(app.parametersPanel, 'push');
            app.saveallButton_2.ButtonPushedFcn = createCallbackFcn(app, @saveallButton_2Pushed, true);
            app.saveallButton_2.Text = 'save all';
            app.input_name_Label_2 = uilabel(app.parametersPanel);
            app.input_name_Label_2.Text = 'Vessel enhance的图片文件名前缀';
            app.VesselenhanceprefixLabel = uilabel(app.parametersPanel);
            app.VesselenhanceprefixLabel.Text = 'Vessel enhance prefix';
            app.VE_prefix_EditField_5 = uieditfield(app.parametersPanel, 'text');
            app.input_name_Label_3 = uilabel(app.parametersPanel);
            app.input_name_Label_3.Text = '已规定化处理的图片文件名前缀';
            app.HistmatchedimagesprefixLabel = uilabel(app.parametersPanel);
            app.HistmatchedimagesprefixLabel.Text = 'Hist-matched images prefix';
            app.matched_prefix_EditField_6 = uieditfield(app.parametersPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MRF_parameter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end