classdef VE_parameter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FileMenu                      matlab.ui.container.Menu
        EditMenu                      matlab.ui.container.Menu
        helponvesselenhancePanel      matlab.ui.container.Panel
        TextAreaLabel                 matlab.ui.control.Label
        TextArea                      matlab.ui.control.TextArea
        TabGroup                      matlab.ui.container.TabGroup
        SingleimageprocessingTab      matlab.ui.container.Tab
        currentmodulevesselenhancePanel  matlab.ui.container.Panel
        imageButtonGroup              matlab.ui.container.ButtonGroup
        imagetovesselenhanceButton    matlab.ui.control.ToggleButton
        image_to_vesselenhance_EditField  matlab.ui.control.EditField
        pathofsaveimageButton         matlab.ui.control.ToggleButton
        path_of_save_image_EditField  matlab.ui.control.EditField
        startButton                   matlab.ui.control.Button
        saveparametersPanel           matlab.ui.container.Panel
        saveallButton                 matlab.ui.control.Button
        outputfilenameEditFieldLabel  matlab.ui.control.Label
        filename_prefix_EditField     matlab.ui.control.EditField
        BatchimageprocessingTab       matlab.ui.container.Tab
        currentmodulevesselenhancePanel_2  matlab.ui.container.Panel
        startButton_2                 matlab.ui.control.Button
        parametersPanel               matlab.ui.container.Panel
        pathofinputimagesButton       matlab.ui.control.Button
        image_to_vessel_enhance_EditField  matlab.ui.control.EditField
        inputimageprefixLabel         matlab.ui.control.Label
        filename_prefix_EditField_3   matlab.ui.control.EditField
        path_of_input_images_Label    matlab.ui.control.Label
        input_name_Label              matlab.ui.control.Label
        output_prefix_Label           matlab.ui.control.Label
        outputprefixLabel             matlab.ui.control.Label
        filename_prefix_EditField_2   matlab.ui.control.EditField
        saveallButton_2               matlab.ui.control.Button
    end

    
    properties (Access = private)
        Calling_app % 回调主APP

        input_img_path
        output_img
        output_prefix
        output_path
        %batch
        input_dir_path
        inputs_prefix
        
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.Calling_app=mainapp;
            app.filename_prefix_EditField.Value="Please enter the prefix of the saved file";          
        end

        % Selection changed function: imageButtonGroup
        function imageButtonGroupSelectionChanged(app, event)
            selectedButton = app.imageButtonGroup.SelectedObject;
%             app.k_means_param_EditField.Value="请输入保存文件的前缀,后点击'filename prefix'";
            switch selectedButton.Text
                case 'image to vessel enhance'
                    [filename,path]=uigetfile({'*.nii.gz;*.nii'},'选择图片文件');
                    app.image_to_vesselenhance_EditField.Value=[path,filename];
                    app.input_img_path=app.image_to_vesselenhance_EditField.Value;
                case 'path of save image'
                    app.output_path = uigetdir();
                    app.path_of_save_image_EditField.Value=app.output_path;
               
            end
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            info=load_untouch_nii(app.input_img_path);
            IMG=info.img;
            hdr=info.hdr;
%             血管增强处理
            spacing=hdr.dime.pixdim(2:4);%分辨率
%             disp(spacing)
            I = IMG - min(IMG(:));
            I = I / prctile(I(I(:) > 0.5 * max(I(:))),90);
            I(I>1) = 1;
            voxSpace=min(spacing);%单位体素
        %     scales=[voxSpace:voxSpace:10*voxSpace]; %For Human
        %     scales=[voxSpace:voxSpace:5*voxSpace]; % For External dataset        
            tau = 1;
            vesselness = vesselness3D(I, [0.5:0.5:3], [1;1;1], tau, true);
        %     vesselness = vesselness3D(I, [0.5:0.5:3], spacing, tau, true);
            Mask=imbinarize(IMG);
            Mask=double(Mask);
            
            vesselness=vesselness.*Mask;

            app.output_img=make_nii(vesselness);
            app.output_img.hdr = hdr; 
            path=[app.output_path,'\',app.output_prefix,'.nii.gz'];
            save_nii(app.output_img, path);
            uialert(app.UIFigure,"Vascular enhancement is complete," + ...
                " and the output image is saved to the corresponding path.",'Notice','Icon','success')
            
            
        end

        % Button pushed function: saveallButton
        function saveallButtonPushed(app, event)
            app.output_prefix=app.filename_prefix_EditField.Value;        
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success')
                
        end

        % Button pushed function: saveallButton_2
        function saveallButton_2Pushed(app, event)
            app.output_prefix=app.filename_prefix_EditField_2.Value; 
            app.inputs_prefix=app.filename_prefix_EditField_3.Value;%非正则表达式
            app.inputs_prefix=regexptranslate("escape",app.inputs_prefix);%转成正则表达式
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success')
  
        end

        % Button pushed function: startButton_2
        function startButton_2Pushed(app, event)
            
            [files_name,files_path]=file_extraction(app.input_dir_path, app.inputs_prefix);
            if isempty(files_name)
                alart_message='There is not the images to be processed in the document!';
                uialert(app.UIFigure,alart_message,'Notice','Icon','error') 
            else
                %进度条显示处理进度
                progress_bar=uiprogressdlg(app.UIFigure,'Title','Please wait','Message','Working on image 1','Value',0);
                for i=1:length(files_name)
                    file=files_name{i};
                    info=load_untouch_nii(file);
                    IMG=info.img;
                    hdr=info.hdr;
                    %              血管增强处理
                    spacing=hdr.dime.pixdim(2:4);%分辨率
        %             disp(spacing)
                    I = IMG - min(IMG(:));
                    I = I / prctile(I(I(:) > 0.5 * max(I(:))),90);
                    I(I>1) = 1;
                    voxSpace=min(spacing);%单位体素
                %     scales=[voxSpace:voxSpace:10*voxSpace]; %For Human
                %     scales=[voxSpace:voxSpace:5*voxSpace]; % For External dataset        
                    tau = 1;
                    vesselness = vesselness3D(I, [0.5:0.5:3], [1;1;1], tau, true);
                %     vesselness = vesselness3D(I, [0.5:0.5:3], spacing, tau, true);
                    Mask=imbinarize(IMG);
                    Mask=double(Mask);
                    
                    vesselness=vesselness.*Mask;
        
                    app.output_img=make_nii(vesselness);
                    app.output_img.hdr = hdr; 
                    
                    [~, endindex]= regexp(file,app.inputs_prefix);%正则比较
                    suffix = file((endindex+1):end);
                    save_path=fullfile(files_path{i},[app.output_prefix,suffix]);
                    save_nii(app.output_img, save_path);
                    %进度条更新
                    progress_bar.Value=i/length(files_name);
                    if i~=length(files_name)
                        progress_bar.Message=sprintf('Working on image %d',i+1);
                    else%关闭进度条
                        close(progress_bar);
                    end
                end
            end
            
           
            uialert(app.UIFigure,['Vessel enhance is complete,' ...
                'and the output images are saved to the corresponding path.'],'Notice','Icon','success')
        
        end

        % Button pushed function: pathofinputimagesButton
        function pathofinputimagesButtonPushed(app, event)
            app.input_dir_path = uigetdir();
            app.image_to_vessel_enhance_EditField.Value=app.input_dir_path;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.helponvesselenhancePanel = uipanel(app.UIFigure);
            app.helponvesselenhancePanel.Title = 'help on vessel enhance';
            app.TextAreaLabel = uilabel(app.helponvesselenhancePanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.helponvesselenhancePanel);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleimageprocessingTab = uitab(app.TabGroup);
            app.SingleimageprocessingTab.Title = 'Single image processing';
            app.currentmodulevesselenhancePanel = uipanel(app.SingleimageprocessingTab);
            app.currentmodulevesselenhancePanel.Title = 'current module:vessel enhance';
            app.imageButtonGroup = uibuttongroup(app.currentmodulevesselenhancePanel);
            app.imageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @imageButtonGroupSelectionChanged, true);
            app.imageButtonGroup.Title = 'image';
            app.imagetovesselenhanceButton = uitogglebutton(app.imageButtonGroup);
            app.imagetovesselenhanceButton.Text = 'image to vessel enhance';
            app.image_to_vesselenhance_EditField = uieditfield(app.imageButtonGroup, 'text');
            app.pathofsaveimageButton = uitogglebutton(app.imageButtonGroup);
            app.pathofsaveimageButton.Text = 'path of save image';
            app.pathofsaveimageButton.Value = true;
            app.path_of_save_image_EditField = uieditfield(app.imageButtonGroup, 'text');
            app.startButton = uibutton(app.currentmodulevesselenhancePanel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.saveparametersPanel = uipanel(app.currentmodulevesselenhancePanel);
            app.saveparametersPanel.Title = 'save parameters';
            app.saveallButton = uibutton(app.saveparametersPanel, 'push');
            app.saveallButton.ButtonPushedFcn = createCallbackFcn(app, @saveallButtonPushed, true);
            app.saveallButton.Text = 'save all';
            app.outputfilenameEditFieldLabel = uilabel(app.saveparametersPanel);
            app.outputfilenameEditFieldLabel.Text = 'output filename';
            app.filename_prefix_EditField = uieditfield(app.saveparametersPanel, 'text');
            app.BatchimageprocessingTab = uitab(app.TabGroup);
            app.BatchimageprocessingTab.Title = 'Batch image processing';
            app.currentmodulevesselenhancePanel_2 = uipanel(app.BatchimageprocessingTab);
            app.currentmodulevesselenhancePanel_2.Title = 'current module:vessel enhance';
            app.startButton_2 = uibutton(app.currentmodulevesselenhancePanel_2, 'push');
            app.startButton_2.ButtonPushedFcn = createCallbackFcn(app, @startButton_2Pushed, true);
            app.startButton_2.Text = 'start';
            app.parametersPanel = uipanel(app.currentmodulevesselenhancePanel_2);
            app.parametersPanel.Title = 'parameters';
            app.pathofinputimagesButton = uibutton(app.parametersPanel, 'push');
            app.pathofinputimagesButton.ButtonPushedFcn = createCallbackFcn(app, @pathofinputimagesButtonPushed, true);
            app.pathofinputimagesButton.Text = 'path of input images';
            app.image_to_vessel_enhance_EditField = uieditfield(app.parametersPanel, 'text');
            app.inputimageprefixLabel = uilabel(app.parametersPanel);
            app.inputimageprefixLabel.Text = 'input image prefix';
            app.filename_prefix_EditField_3 = uieditfield(app.parametersPanel, 'text');
            app.path_of_input_images_Label = uilabel(app.parametersPanel);
            app.path_of_input_images_Label.Text = '选择批量图片集所在的大文件夹，图片集以子文件夹形式划分';
            app.input_name_Label = uilabel(app.parametersPanel);
            app.input_name_Label.Text = '待批量处理的图片文件名前缀';
            app.output_prefix_Label = uilabel(app.parametersPanel);
            app.output_prefix_Label.Text = '相关输出图像的前缀名，存放在输入图片所在的子文件夹';
            app.outputprefixLabel = uilabel(app.parametersPanel);
            app.outputprefixLabel.Text = 'output prefix';
            app.filename_prefix_EditField_2 = uieditfield(app.parametersPanel, 'text');
            app.saveallButton_2 = uibutton(app.parametersPanel, 'push');
            app.saveallButton_2.ButtonPushedFcn = createCallbackFcn(app, @saveallButton_2Pushed, true);
            app.saveallButton_2.Text = 'save all';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VE_parameter(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end