classdef hist_parameter_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        EditMenu                        matlab.ui.container.Menu
        helponhistogrammatchingPanel    matlab.ui.container.Panel
        TextAreaLabel                   matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        TabGroup                        matlab.ui.container.TabGroup
        SingleimageprocessingTab        matlab.ui.container.Tab
        currentmodulehistogrammatchingPanel  matlab.ui.container.Panel
        imageButtonGroup                matlab.ui.container.ButtonGroup
        imagetohistogrammatchingButton  matlab.ui.control.ToggleButton
        image_to_histogram_matching_EditField  matlab.ui.control.EditField
        pathofsaveimageButton           matlab.ui.control.ToggleButton
        path_of_save_image_EditField    matlab.ui.control.EditField
        startButton                     matlab.ui.control.Button
        parametersPanel                 matlab.ui.container.Panel
        outputfilenameEditFieldLabel    matlab.ui.control.Label
        filename_prefix_EditField       matlab.ui.control.EditField
        saveallButton                   matlab.ui.control.Button
        set_the_target_hist_EditField   matlab.ui.control.EditField
        setthetargethistogramButton     matlab.ui.control.Button
        BatchimageprocessingTab         matlab.ui.container.Tab
        currentmodulehistogrammatchingPanel_2  matlab.ui.container.Panel
        startButton_2                   matlab.ui.control.Button
        parametersPanel_3               matlab.ui.container.Panel
        pathofinputimagesButton         matlab.ui.control.Button
        image_to_histogram_matching_EditField_2  matlab.ui.control.EditField
        inputimageprefixLabel           matlab.ui.control.Label
        filename_prefix_EditField_4     matlab.ui.control.EditField
        path_of_input_images_Label      matlab.ui.control.Label
        input_name_Label                matlab.ui.control.Label
        output_prefix_Label             matlab.ui.control.Label
        traget_Label                    matlab.ui.control.Label
        outputprefixLabel               matlab.ui.control.Label
        filename_prefix_EditField_2     matlab.ui.control.EditField
        saveallButton_2                 matlab.ui.control.Button
        setthetargethistogramButton_2   matlab.ui.control.Button
        set_the_target_hist_EditField_2  matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Calling_app % 回调主APP
        target_hist
        input_img_path
        output_img
        output_prefix
        output_path
        input_dir_path
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.Calling_app=mainapp;
            app.filename_prefix_EditField.Value="Please enter the prefix of the saved file";
            
        end

        % Selection changed function: imageButtonGroup
        function imageButtonGroupSelectionChanged(app, event)
            selectedButton = app.imageButtonGroup.SelectedObject;
%             app.filename_prefix_EditField.Value="请输入保存文件的前缀,后点击'filename prefix'";
            switch selectedButton.Text
                case 'image to histogram matching'
                    [filename,path]=uigetfile({'*.nii.gz;*.nii'},'选择图片文件');
                    app.image_to_histogram_matching_EditField.Value=[path,filename];
                    app.input_img_path=app.image_to_histogram_matching_EditField.Value;
                    app.TextArea.Value="请单击按钮'image to histogram matching'导入要进行直方图规定化的图像，仅限于NIFTI格式图像";
                case 'path of save image'
                    app.output_path = uigetdir();
                    app.path_of_save_image_EditField.Value=app.output_path;
            end
        end

        % Button pushed function: startButton
        function startButtonPushed(app, event)
            disp(app.target_hist)
            Trans_Image=histMatching(app.input_img_path,app.target_hist);
            app.output_img = make_nii(Trans_Image);
            input_data=load_untouch_nii(app.input_img_path);
            app.output_img.hdr = input_data.hdr;
            path=[app.output_path,'\',app.output_prefix,'.nii.gz'];
            save_nii(app.output_img, path);
            uialert(app.UIFigure,['Histogram matching is complete,' ...
                'and the output image is saved to the corresponding path.'],'Notice','Icon','success')
            
        end

        % Button pushed function: saveallButton
        function saveallButtonPushed(app, event)
%             app.target_hist=importdata(app.set_the_target_hist_EditField.Value);
            app.output_prefix=app.filename_prefix_EditField.Value;
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success') 
        end

        % Button pushed function: setthetargethistogramButton
        function setthetargethistogramButtonPushed(app, event)
            [filename,path]=uigetfile({'*.mat'},'选择mat文件');
            app.set_the_target_hist_EditField.Value=[path,filename];
            app.target_hist=[path,filename];   
            disp(app.target_hist)
        end

        % Button pushed function: saveallButton_2
        function saveallButton_2Pushed(app, event)
            app.target_hist=importdata(app.set_the_target_hist_EditField_2.Value);
            app.output_prefix=app.filename_prefix_EditField_2.Value;
            app.inputs_prefix=app.filename_prefix_EditField_4.Value;%非正则表达式
            app.inputs_prefix=regexptranslate("escape",app.inputs_prefix);%转成正则表达式
            uialert(app.UIFigure,'Input parameters have been saved!','Notice','Icon','success') 
        end

        % Button pushed function: startButton_2
        function startButton_2Pushed(app, event)
            [files_name,files_path]=file_extraction(app.input_dir_path, app.inputs_prefix);
            if isempty(files_name)
                alart_message='There is not the images to be processed in the document!';
                uialert(app.UIFigure,alart_message,'Notice','Icon','error') 
            else
                %进度条显示处理进度
                progress_bar=uiprogressdlg(app.UIFigure,'Title','Please wait','Message','Working on image 1','Value',0);
                for i=1:length(files_name)
                    target_file=files_name{i};
                    Trans_Image=histMatching(target_file,app.target_hist);
                    app.output_img = make_nii(Trans_Image);
                    input_data=load_untouch_nii(target_file);
                    app.output_img.hdr = input_data.hdr;
                    
                    [~, endindex]= regexp(target_file,app.inputs_prefix);
                    suffix = target_file((endindex+1):end);
                    save_path=fullfile(files_path{i},[app.output_prefix,suffix]);
                    save_nii(app.output_img, save_path);
                    %进度条更新
                    progress_bar.Value=i/length(files_name);
                    if i~=length(files_name)
                        progress_bar.Message=sprintf('Working on image %d',i+1);
                    else%关闭进度条
                        close(progress_bar);
                    end
                end
            end
            uialert(app.UIFigure,['Histogram matching is complete,' ...
                'and the output images are saved to the corresponding path.'],'Notice','Icon','success')
        end

        % Button pushed function: pathofinputimagesButton
        function pathofinputimagesButtonPushed(app, event)
            app.input_dir_path = uigetdir();
            app.image_to_histogram_matching_EditField_2.Value=app.input_dir_path;
            
        end

        % Button pushed function: setthetargethistogramButton_2
        function setthetargethistogramButton_2Pushed(app, event)
            [filename,path]=uigetfile({'*.mat'},'选择mat文件');
            app.set_the_target_hist_EditField_2.Value=[path,filename];
            app.target_hist=[path,filename];   
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.helponhistogrammatchingPanel = uipanel(app.UIFigure);
            app.helponhistogrammatchingPanel.Title = 'help on histogram matching';
            app.TextAreaLabel = uilabel(app.helponhistogrammatchingPanel);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.helponhistogrammatchingPanel);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SingleimageprocessingTab = uitab(app.TabGroup);
            app.SingleimageprocessingTab.Title = 'Single image processing';
            app.currentmodulehistogrammatchingPanel = uipanel(app.SingleimageprocessingTab);
            app.currentmodulehistogrammatchingPanel.Title = 'current module:histogram matching';
            app.imageButtonGroup = uibuttongroup(app.currentmodulehistogrammatchingPanel);
            app.imageButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @imageButtonGroupSelectionChanged, true);
            app.imageButtonGroup.Title = 'image';
            app.imagetohistogrammatchingButton = uitogglebutton(app.imageButtonGroup);
            app.imagetohistogrammatchingButton.Text = 'image to histogram matching';
            app.image_to_histogram_matching_EditField = uieditfield(app.imageButtonGroup, 'text');
            app.pathofsaveimageButton = uitogglebutton(app.imageButtonGroup);
            app.pathofsaveimageButton.Text = 'path of save image';
            app.pathofsaveimageButton.Value = true;
            app.path_of_save_image_EditField = uieditfield(app.imageButtonGroup, 'text');
            app.startButton = uibutton(app.currentmodulehistogrammatchingPanel, 'push');
            app.startButton.ButtonPushedFcn = createCallbackFcn(app, @startButtonPushed, true);
            app.startButton.Text = 'start';
            app.parametersPanel = uipanel(app.currentmodulehistogrammatchingPanel);
            app.parametersPanel.Title = 'parameters';
            app.outputfilenameEditFieldLabel = uilabel(app.parametersPanel);
            app.outputfilenameEditFieldLabel.Text = 'output filename';
            app.filename_prefix_EditField = uieditfield(app.parametersPanel, 'text');
            app.saveallButton = uibutton(app.parametersPanel, 'push');
            app.saveallButton.ButtonPushedFcn = createCallbackFcn(app, @saveallButtonPushed, true);
            app.saveallButton.Text = 'save all';
            app.set_the_target_hist_EditField = uieditfield(app.parametersPanel, 'text');
            app.setthetargethistogramButton = uibutton(app.parametersPanel, 'push');
            app.setthetargethistogramButton.ButtonPushedFcn = createCallbackFcn(app, @setthetargethistogramButtonPushed, true);
            app.setthetargethistogramButton.Text = 'set the target histogram';
            app.BatchimageprocessingTab = uitab(app.TabGroup);
            app.BatchimageprocessingTab.Title = 'Batch image processing';
            app.currentmodulehistogrammatchingPanel_2 = uipanel(app.BatchimageprocessingTab);
            app.currentmodulehistogrammatchingPanel_2.Title = 'current module:histogram matching';
            app.startButton_2 = uibutton(app.currentmodulehistogrammatchingPanel_2, 'push');
            app.startButton_2.ButtonPushedFcn = createCallbackFcn(app, @startButton_2Pushed, true);
            app.startButton_2.Text = 'start';
            app.parametersPanel_3 = uipanel(app.currentmodulehistogrammatchingPanel_2);
            app.parametersPanel_3.Title = 'parameters';
            app.pathofinputimagesButton = uibutton(app.parametersPanel_3, 'push');
            app.pathofinputimagesButton.ButtonPushedFcn = createCallbackFcn(app, @pathofinputimagesButtonPushed, true);
            app.pathofinputimagesButton.Text = 'path of input images';
            app.image_to_histogram_matching_EditField_2 = uieditfield(app.parametersPanel_3, 'text');
            app.inputimageprefixLabel = uilabel(app.parametersPanel_3);
            app.inputimageprefixLabel.Text = 'input image prefix';
            app.filename_prefix_EditField_4 = uieditfield(app.parametersPanel_3, 'text');
            app.path_of_input_images_Label = uilabel(app.parametersPanel_3);
            app.path_of_input_images_Label.Text = '选择批量图片集所在的大文件夹，图片集以子文件夹形式划分';
            app.input_name_Label = uilabel(app.parametersPanel_3);
            app.input_name_Label.Text = '待批量处理的图片文件名前缀';
            app.output_prefix_Label = uilabel(app.parametersPanel_3);
            app.output_prefix_Label.Text = '相关输出图像的前缀名，存放在输入图片所在的子文件夹';
            app.traget_Label = uilabel(app.parametersPanel_3);
            app.traget_Label.Text = '设置用于直方图规定化的组标准直方图';
            app.outputprefixLabel = uilabel(app.parametersPanel_3);
            app.outputprefixLabel.Text = 'output prefix';
            app.filename_prefix_EditField_2 = uieditfield(app.parametersPanel_3, 'text');
            app.saveallButton_2 = uibutton(app.parametersPanel_3, 'push');
            app.saveallButton_2.ButtonPushedFcn = createCallbackFcn(app, @saveallButton_2Pushed, true);
            app.saveallButton_2.Text = 'save all';
            app.setthetargethistogramButton_2 = uibutton(app.parametersPanel_3, 'push');
            app.setthetargethistogramButton_2.ButtonPushedFcn = createCallbackFcn(app, @setthetargethistogramButton_2Pushed, true);
            app.setthetargethistogramButton_2.Text = 'set the target histogram';
            app.set_the_target_hist_EditField_2 = uieditfield(app.parametersPanel_3, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = hist_parameter_2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end